classdef GUI_select_thr_and_area < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        ThresholdSliderLabel   matlab.ui.control.Label
        ThresholdSlider        matlab.ui.control.Slider
        minareaSliderLabel     matlab.ui.control.Label
        minareaSlider          matlab.ui.control.Slider
        PLOTButton             matlab.ui.control.Button
        ENDButton              matlab.ui.control.Button
        brightnessSliderLabel  matlab.ui.control.Label
        brightnessSlider       matlab.ui.control.Slider
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        IMAGE % Description
        threshold
        MIN_AREA
        brightness
        image_after_bright
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, frame_to_read, THR, min_area, bright)
            %             app.IMAGE = frame_to_read;
            % get the brightness in percent
            median_img = double(median(frame_to_read(:)));
            val_bright = median_img * bright;
            binary_image = imbinarize(255-frame_to_read + (val_bright/100) ,THR); %0.92 0.75
            mouse = bwareaopen(binary_image, min_area) * 255;
            % set the default variables
            app.minareaSlider.Value = min_area;
            app.ThresholdSlider.Value = THR;
            % plot the image with default values
            imagesc(app.UIAxes, mouse)
            imagesc(app.UIAxes2, frame_to_read)
            app.IMAGE = frame_to_read;
            app.brightness = bright;
            % init image
            app.image_after_bright = [frame_to_read];
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ThresholdSlider
        function adjust_thre(app, event)
            %             THR = app.ThresholdSlider.Value;
            %             min_area = app.minareaSlider.Value;
            make_plot(app);
            
        end

        % Value changed function: minareaSlider
        function adjust_area(app, event)
            %             min_area = app.minareaSlider.Value;
            %             THR = app.ThresholdSlider.Value;
            make_plot(app);
        end

        % Button pushed function: PLOTButton
        function make_plot(app, event)
            min_area =  round(app.minareaSlider.Value);
            THR = app.ThresholdSlider.Value;
            frame_to_read = app.IMAGE;
            median_img = double(median(frame_to_read(:)));
            val_bright = median_img * (app.brightnessSlider.Value/100);
            binary_image = imbinarize(255-frame_to_read + (val_bright),THR); %0.92 0.75
            mouse = bwareaopen(binary_image, min_area) * 255;
            imagesc(app.UIAxes, mouse)
            imagesc(app.UIAxes2, frame_to_read + (val_bright))
            
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            make_plot(app, changingValue)
            
        end

        % Button pushed function: ENDButton
        function get_vals_out(app, event)
            app.threshold = app.ThresholdSlider.Value;
            app.MIN_AREA = round(app.minareaSlider.Value);
            app.ENDButton.UserData=1;
        end

        % Value changed function: brightnessSlider
        function change_brightness(app, event)
            value = app.brightnessSlider.Value;
%             keyboard
            median_img = double(median(app.IMAGE(:)));
            val_bright = median_img * (value/100);
            frame_to_read= app.IMAGE + val_bright;
            imagesc(app.UIAxes2,frame_to_read)
            app.brightness = value;
             make_plot(app);
            
%             app.IMAGE = frame_to_read;
%             app.image_after_bright = frame_to_read;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ThresholdSliderLabel = uilabel(app.LeftPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.LeftPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @adjust_thre, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.minareaSliderLabel = uilabel(app.LeftPanel);
            app.minareaSliderLabel.Text = 'min area';
            app.minareaSlider = uislider(app.LeftPanel);
            app.minareaSlider.Limits = [0 5000];
            app.minareaSlider.ValueChangedFcn = createCallbackFcn(app, @adjust_area, true);
            app.PLOTButton = uibutton(app.LeftPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @make_plot, true);
            app.PLOTButton.Text = 'PLOT';
            app.ENDButton = uibutton(app.LeftPanel, 'push');
            app.ENDButton.ButtonPushedFcn = createCallbackFcn(app, @get_vals_out, true);
            app.ENDButton.Text = 'END';
            app.brightnessSliderLabel = uilabel(app.LeftPanel);
            app.brightnessSliderLabel.Text = 'brightness';
            app.brightnessSlider = uislider(app.LeftPanel);
            app.brightnessSlider.Limits = [-100 100];
            app.brightnessSlider.ValueChangedFcn = createCallbackFcn(app, @change_brightness, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Segmentation of the animal')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Filtered image')
            xlabel(app.UIAxes2, {''; ''})
            ylabel(app.UIAxes2, {''; ''})
            zlabel(app.UIAxes2, {''; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_select_thr_and_area(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end